classdef Snakes_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        CancelButton_2                 matlab.ui.control.Button
        StartButton_2                  matlab.ui.control.Button
        SnakeTypeButtonGroup           matlab.ui.container.ButtonGroup
        OpenButton                     matlab.ui.control.RadioButton
        ClosedButton                   matlab.ui.control.RadioButton
        UploadImageButton              matlab.ui.control.Button
        IterationsSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        AlphaSpinnerLabel              matlab.ui.control.Label
        AlphaSpinner                   matlab.ui.control.Spinner
        BetaSpinnerLabel               matlab.ui.control.Label
        BetaSpinner                    matlab.ui.control.Spinner
        SigmaSpinnerLabel              matlab.ui.control.Label
        SigmaSpinner                   matlab.ui.control.Spinner
        GammaSpinnerLabel              matlab.ui.control.Label
        GammaSpinner                   matlab.ui.control.Spinner
        w_lineSpinnerLabel             matlab.ui.control.Label
        w_lineSpinner                  matlab.ui.control.Spinner
        w_edgeSpinnerLabel             matlab.ui.control.Label
        w_edgeSpinner                  matlab.ui.control.Spinner
        w_termSpinnerLabel             matlab.ui.control.Label
        w_termSpinner                  matlab.ui.control.Spinner
        EdgeEnergyFunctionButtonGroup  matlab.ui.container.ButtonGroup
        SobelButton                    matlab.ui.control.RadioButton
        PrewittButton                  matlab.ui.control.RadioButton
        RobertsButton                  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        SnakeType %Open/Closed
        Image
        Iterations
        Alpha
        Beta
        Gamma
        Sigma
        w_term
        w_edge
        w_line
        EdgeEnergy % Roberts / Sobel / Prewitt
        x,
        y,
        fig
    end
    
    methods (Access = public)
        
        function UploadImage(app, imageFile)
            try
                app.Image = imread(imageFile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
            imshow(app.Image,'parent',app.UIAxes)
            hold(app.UIAxes, "on");
            
            if (ndims(app.Image) == 3)
                app.Image = rgb2gray(app.Image);
            end
            
            [app.x,app.y] = InitializeUISnake(app);
            
        end
        
        function [x, y] = InitializeUISnake(app)
            I = app.Image;
            snake = app.SnakeType;
            
            imshow(app.Image, 'parent', app.UIAxes)
            hold(app.UIAxes, "on");
            
            max_len = max(size(I)) - 1;
            axis([0 1 0 1]);
            
            imshow(app.Image ,'parent',app.UIAxes)
            
            imshow(app.Image)            
            [x, y] = getpts();   
            hold on;
                        
            x = transpose(x);
            y = transpose(y);
            
            if(snake =="Closed")
                x = [x, x(1)];
                y = [y, y(1)];
                knots = [x ; y];
                number_of_points = length(x);
                distance_points = 1:number_of_points;
                final_distance_points = 1:0.05:number_of_points;
                closed_curve = spline(distance_points, knots, final_distance_points);
                closed_curve(closed_curve < 1) = 1;
                closed_curve(closed_curve > max_len) = max_len;
                x_new = closed_curve(1,:);
                y_new = closed_curve(2,:);
                plot(app.UIAxes, x ,y, 'o', x_new, y_new, '--');
                x = x_new;
                y = y_new;
                
            else
                knots = [x ; y];
                number_of_points = length(x);
                distance_points = 1:number_of_points;
                final_distance_points = 1:0.09:number_of_points;
                Open_Curve = spline(distance_points, knots, final_distance_points);
                
                Open_Curve(Open_Curve < 1) = 1;
                Open_Curve(Open_Curve > max_len) = max_len;
                x_new = Open_Curve(1,:);
                y_new = Open_Curve(2,:);
                plot(app.UIAxes,x ,y, 'o', x_new, y_new, '--');
                x = x_new;
                y = y_new;
                
            end
            
            hold(app.UIAxes, "on");
        end
        
        function Snake(app)
            
            snakeType = app.SnakeType;
            N = app.Iterations;
            alpha =app.Alpha;
            beta = app.Beta;
            gamma = app.Gamma;
            wLine = app.w_line;
            wEdge = app.w_edge;
            wTerm = app.w_term;
            sigma = app.Sigma;
            x1 = app.x;
            y1 = app.y;
            I = app.Image;
            
            energyBtn = app.EdgeEnergyFunctionButtonGroup.SelectedObject;
            app.EdgeEnergy = get(energyBtn,'Text');
            
            I_after_gaussian_filter = double(imgaussfilt(I, sigma));
            %figure,imtool(I_after_gaussian_filter)
            external_energy = ExternalEnergyCal(I_after_gaussian_filter, wLine, wEdge, wTerm);
            %figure,imtool(external_energy)
            
            % calcualte the inverse matrix of A
            a_inverse = InternalEnergyCal(size(x1, 2), alpha, beta, gamma, snakeType);
            x1 = x1';
            y1 = y1';
            
            edge_x = [1 0 -1;2 0 -2; 1 0 -1];
            edge_y = [1 2 1; 0 0 0; -1 -2 -1];
            
            if(app.EdgeEnergy == "Sobel")
            
                edge_x = [1 0 -1;2 0 -2; 1 0 -1];
                edge_y = [1 2 1; 0 0 0; -1 -2 -1];
                
            end

            if(app.EdgeEnergy == "Prewitt")
            
                edge_x = [1 0 -1;1 0 -1; 1 0 -1];
                edge_y = [1 1 1; 0 0 0; -1 -1 -1];
            
            end
             if(app.EdgeEnergy == "Roberts")
            
                edge_x = [1 0 0; 0 -1 0; 0 0 0];
                edge_y = [0 1 0; -1 0 0; 0 0 0];
            
             end
            
            fx = conv2(external_energy, edge_x, 'same');
            fy = conv2(external_energy, edge_y, 'same');
            
            steps = floor(N/30);
            
            for i = 1:N
                [x1, y1] = iteration(a_inverse, x1, y1, external_energy, gamma, fx, fy);
                imshow(app.Image,'parent', app.UIAxes);
                hold(app.UIAxes, "on");
                plot(app.UIAxes,x1, y1, 'r');
                if(mod(i, steps) == 0)
                    fprintf('%d/%d interations\n', i, N);
                end
                pause(0.0001);
            end
            
            if(mod(i, steps) == 0)
                fprintf('%d/%d interations\n', N, N);
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIAxes.Visible = 'off';
            app.UIFigure.Colormap = gray(256);
            axis(app.UIAxes,'image')
            %hold on;
            %app.fig = figure;
            app.Iterations = app.IterationsSpinner.Value;
            sT = app.SnakeTypeButtonGroup.SelectedObject;
            app.SnakeType = get(sT,'Text');
            app.Alpha = app.AlphaSpinner.Value;
            app.Beta = app.BetaSpinner.Value;
            app.Sigma = app.SigmaSpinner.Value;
            app.Gamma = app.GammaSpinner.Value;
            app.w_line = app.w_lineSpinner.Value;
            app.w_edge = app.w_edgeSpinner.Value;
            app.w_term = app.w_termSpinner.Value;
            energyBtn = app.EdgeEnergyFunctionButtonGroup.SelectedObject;
            app.EdgeEnergy = get(energyBtn,'Text');
            
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               UploadImage(app, fname);
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.Iterations = app.IterationsSpinner.Value;
            sT = app.SnakeTypeButtonGroup.SelectedObject;
            app.SnakeType = get(sT,'Text');
            app.Alpha = app.AlphaSpinner.Value;
            app.Beta = app.BetaSpinner.Value;
            app.Sigma = app.SigmaSpinner.Value;
            app.Gamma = app.GammaSpinner.Value;
            app.w_line = app.w_lineSpinner.Value;
            app.w_edge = app.w_edgeSpinner.Value;
            app.w_term = app.w_termSpinner.Value;
            energyBtn = app.EdgeEnergyFunctionButtonGroup.SelectedObject;
            app.EdgeEnergy = get(energyBtn,'Text');
            
            
            disp(app.Iterations)
            disp(app.SnakeType)
            disp(app.Alpha)
            disp(app.Beta)
            disp(app.Sigma)
            disp(app.Gamma)
            disp(app.w_line)
            disp(app.w_edge)
            disp(app.w_term)
            disp(app.EdgeEnergy)
            
            
            Snake(app)
            
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            app.Iterations = app.IterationsSpinner.Value;
            disp(app.Iterations)
        end

        % Selection changed function: SnakeTypeButtonGroup
        function SnakeTypeButtonGroupSelectionChanged(app, event)
            sT = app.SnakeTypeButtonGroup.SelectedObject;
            app.SnakeType = get(sT,'Text');
            disp(app.SnakeType)
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            app.Alpha = app.AlphaSpinner.Value;
            disp(app.Alpha)
        end

        % Value changed function: BetaSpinner
        function BetaSpinnerValueChanged(app, event)
            app.Beta = app.BetaSpinner.Value;
            disp(app.Beta)
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChanged(app, event)
            app.Sigma = app.SigmaSpinner.Value;
            disp(app.Sigma)
        end

        % Value changed function: GammaSpinner
        function GammaSpinnerValueChanged(app, event)
            app.Gamma = app.GammaSpinner.Value;
            disp(app.Gamma)
        end

        % Value changed function: w_lineSpinner
        function w_lineSpinnerValueChanged(app, event)
            app.w_line = app.w_lineSpinner.Value;
            disp(app.w_line)
        end

        % Value changed function: w_edgeSpinner
        function w_edgeSpinnerValueChanged(app, event)
            app.w_edge = app.w_edgeSpinner.Value;
            disp(app.w_edge)
        end

        % Value changed function: w_termSpinner
        function w_termSpinnerValueChanged(app, event)
            app.w_term = app.w_termSpinner.Value;
            disp(app.w_term)
        end

        % Selection changed function: EdgeEnergyFunctionButtonGroup
        function EdgeEnergyFunctionButtonGroupSelectionChanged(app, event)
            energyBtn = app.EdgeEnergyFunctionButtonGroup.SelectedObject;
            app.EdgeEnergy = get(energyBtn,'Text');
            disp(app.EdgeEnergy)
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
            set(app.UIAxes.cla);
            app.UIFigure.Colormap = gray(256);
            axis(app.UIAxes,'image');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CancelButton_2 = uibutton(app.UIFigure, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Text = 'Cancel';
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.SnakeTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SnakeTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SnakeTypeButtonGroupSelectionChanged, true);
            app.SnakeTypeButtonGroup.Title = 'Snake Type';
            app.OpenButton = uiradiobutton(app.SnakeTypeButtonGroup);
            app.OpenButton.Text = 'Open';
            app.ClosedButton = uiradiobutton(app.SnakeTypeButtonGroup);
            app.ClosedButton.Text = 'Closed';
            app.ClosedButton.Value = true;
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.IterationsSpinnerLabel = uilabel(app.UIFigure);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.UIFigure);
            app.IterationsSpinner.Limits = [50 50000];
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 500;
            app.AlphaSpinnerLabel = uilabel(app.UIFigure);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.UIFigure);
            app.AlphaSpinner.Step = 0.1;
            app.AlphaSpinner.Limits = [-20 20];
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.AlphaSpinner.Value = 0.08;
            app.BetaSpinnerLabel = uilabel(app.UIFigure);
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.UIFigure);
            app.BetaSpinner.Step = 0.1;
            app.BetaSpinner.Limits = [-20 20];
            app.BetaSpinner.ValueChangedFcn = createCallbackFcn(app, @BetaSpinnerValueChanged, true);
            app.BetaSpinner.Value = 0.5;
            app.SigmaSpinnerLabel = uilabel(app.UIFigure);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.UIFigure);
            app.SigmaSpinner.Step = 0.1;
            app.SigmaSpinner.Limits = [-20 20];
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged, true);
            app.SigmaSpinner.Value = 0.5;
            app.GammaSpinnerLabel = uilabel(app.UIFigure);
            app.GammaSpinnerLabel.Text = 'Gamma';
            app.GammaSpinner = uispinner(app.UIFigure);
            app.GammaSpinner.Step = 0.1;
            app.GammaSpinner.Limits = [-20 20];
            app.GammaSpinner.ValueChangedFcn = createCallbackFcn(app, @GammaSpinnerValueChanged, true);
            app.GammaSpinner.Value = 0.8;
            app.w_lineSpinnerLabel = uilabel(app.UIFigure);
            app.w_lineSpinnerLabel.Text = 'w_line';
            app.w_lineSpinner = uispinner(app.UIFigure);
            app.w_lineSpinner.Step = 0.1;
            app.w_lineSpinner.Limits = [-20 20];
            app.w_lineSpinner.ValueChangedFcn = createCallbackFcn(app, @w_lineSpinnerValueChanged, true);
            app.w_lineSpinner.Value = 2.5;
            app.w_edgeSpinnerLabel = uilabel(app.UIFigure);
            app.w_edgeSpinnerLabel.Text = 'w_edge';
            app.w_edgeSpinner = uispinner(app.UIFigure);
            app.w_edgeSpinner.Step = 0.1;
            app.w_edgeSpinner.Limits = [-20 20];
            app.w_edgeSpinner.ValueChangedFcn = createCallbackFcn(app, @w_edgeSpinnerValueChanged, true);
            app.w_edgeSpinner.Value = 4.5;
            app.w_termSpinnerLabel = uilabel(app.UIFigure);
            app.w_termSpinnerLabel.Text = 'w_term';
            app.w_termSpinner = uispinner(app.UIFigure);
            app.w_termSpinner.Step = 0.1;
            app.w_termSpinner.Limits = [-20 20];
            app.w_termSpinner.ValueChangedFcn = createCallbackFcn(app, @w_termSpinnerValueChanged, true);
            app.w_termSpinner.Value = 3.5;
            app.EdgeEnergyFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.EdgeEnergyFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgeEnergyFunctionButtonGroupSelectionChanged, true);
            app.EdgeEnergyFunctionButtonGroup.Title = 'Edge Energy Function';
            app.SobelButton = uiradiobutton(app.EdgeEnergyFunctionButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.SobelButton.Value = true;
            app.PrewittButton = uiradiobutton(app.EdgeEnergyFunctionButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uiradiobutton(app.EdgeEnergyFunctionButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snakes_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end